classdef interfazfrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfazGraficaUIFigure    matlab.ui.Figure
        DataMenu                   matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        TablexlsxMenu              matlab.ui.container.Menu
        GeneralMenu                matlab.ui.container.Menu
        OctaveFilterMenu           matlab.ui.container.Menu
        ThirdOctaveFilterMenu      matlab.ui.container.Menu
        IRJPGMenu                  matlab.ui.container.Menu
        SmoothIRJPGMenu            matlab.ui.container.Menu
        FrecuencyResponseJPGMenu   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        Figures                    matlab.ui.container.TabGroup
        IR                         matlab.ui.container.Tab
        Plot_IR                    matlab.ui.control.UIAxes
        SmoothIR                   matlab.ui.container.Tab
        Plot_SmoothIr              matlab.ui.control.UIAxes
        FrecuencyResponseTab       matlab.ui.container.Tab
        Plot_FrecuencyResponse     matlab.ui.control.UIAxes
        Parameters                 matlab.ui.container.Tab
        Parameters_Filter          matlab.ui.container.TabGroup
        Parameters_General         matlab.ui.container.Tab
        Tabla_General              matlab.ui.control.Table
        Parameters_Octave          matlab.ui.container.Tab
        Tabla_Octave               matlab.ui.control.Table
        Parameters_ThirdOctave     matlab.ui.container.Tab
        Tabla_ThirdOctave          matlab.ui.control.Table
        Operaciones                matlab.ui.container.TabGroup
        CalibrateTab               matlab.ui.container.Tab
        PlaypinknoisetocalibrateyoursystemLabel  matlab.ui.control.Label
        dBLabel                    matlab.ui.control.Label
        Vumetro                    matlab.ui.control.LinearGauge
        Switch                     matlab.ui.control.RockerSwitch
        PlayRecTab                 matlab.ui.container.Tab
        StartHzLabel               matlab.ui.control.Label
        StartHzEditField           matlab.ui.control.NumericEditField
        EndHzLabel                 matlab.ui.control.Label
        EndHzEditField             matlab.ui.control.NumericEditField
        DurationSEditFieldLabel    matlab.ui.control.Label
        DurationSEditField         matlab.ui.control.NumericEditField
        StartMeasurementButton     matlab.ui.control.Button
        PlayandrecordasinesweepinordertoperformthemeasurementLabel  matlab.ui.control.Label
        SelectyourDeviceLabel      matlab.ui.control.Label
        SelectyourDeviceListBox    matlab.ui.control.ListBox
        RepetitionsEditFieldLabel  matlab.ui.control.Label
        RepetitionsEditField       matlab.ui.control.NumericEditField
        ImportTab                  matlab.ui.container.Tab
        ImportCalculateButton      matlab.ui.control.StateButton
        Label                      matlab.ui.control.Label
        EXITButton                 matlab.ui.control.Button
        OctaveFilter_Panel         matlab.ui.container.Panel
        OctaveFilterCheckBox       matlab.ui.control.CheckBox
        OctaveFiter_Select         matlab.ui.control.DropDown
        ThirdOctaveFilter_Panel    matlab.ui.container.Panel
        ThirdOctaveFilterCheckBox  matlab.ui.control.CheckBox
        ThirdOctaveFiter_Select    matlab.ui.control.DropDown
        NoFilter_Panel             matlab.ui.container.Panel
        NoFilterCheckBox           matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
            
            %%%%%%%%%% Ploteo de respuesta en Frecuencia %%%%%%%%%%
            function ploteofrecuencia(app, event)
                dBy = 20*log10(fft(event.amplitudvector));
                semilogx(app.Plot_FrecuencyResponse,abs(dBy));                            %Grafico con x
                app.Plot_FrecuencyResponse.XLim = [0 22050];                              %Logaritmico
                app.Plot_FrecuencyResponse.XTickMode = 'manual';
                app.Plot_FrecuencyResponse.XTick = ([10 31 63 125 250 ...                 %Valores que se van a
                    500 1000 2000 4000 8000 20000]);                                      %mostrar de x
            end

        
            function ploteoir(app, Signal, Irfs)
                
                %%%%%%%%%% Ploteo de respuesta al Impulso %%%%%%%%%%
                y = Signal.amplitudvector;
                
                x = 0:1/Irfs:(length(y)-1)/Irfs;
                plot(app.Plot_IR , x, y);
                app.Plot_IR.XLim = [0 (length(y)-1)/Irfs];
                
            end
                            
                            function ploteosmoth(app,Suavizada, Irfs)
                            
                            %%%%%%%%%% Ploteo de Smooth Respuesta al Impulso %%%%%%%%%%
                            x = 0:1/Irfs:(length(Suavizada.General)-1)/Irfs;
                            plot(app.Plot_SmoothIr, x, Suavizada.General);
                            app.Plot_SmoothIr.XLim = [0 (length(Suavizada.General)-1)/Irfs];
                            end
                            
                            
                            function Tablas(app, Parametros)
                            
                            app.Tabla_General.Data = Parametros.General;
                            app.Tabla_General.ColumnName = ({"EDT", "T10", "T20", "T30", "C80", "D50"});
                            
                            
                            app.Tabla_Octave.Data = Parametros.Octava;
                            app.Tabla_Octave.ColumnName = ({"EDT", "T10", "T20", "T30", "C80", "D50"});
                            app.Tabla_Octave.RowName = ({"125 Hz","250 Hz", "500 Hz" "1000 Hz", "2000 Hz", "4000 Hz","8000 Hz"});
                            
                            
                            app.Tabla_ThirdOctave.Data = Parametros.Tercio;
                            app.Tabla_ThirdOctave.ColumnName = ({"EDT", "T10", "T20", "T30", "C80", "D50"});
                            app.Tabla_ThirdOctave.RowName = ({"125 Hz", "160 Hz",...
                                "200 Hz", "250 Hz", "315 Hz", "400 Hz",...
                                "500 Hz", "630 Hz", "800 Hz", "1000 Hz", "1250 Hz",...
                                "1600 Hz", "2000 Hz", "3150 Hz", "3500 Hz", "4000 Hz",...
                                "5000 Hz", "6300 Hz", "8000 Hz"});
                            
                            end
                    end
                    

    methods (Access = private)

        % Value changed function: Switch
        function PlayNoiseButtonPushed(app, event)
                    val = app.Switch.Value;
                    if val == "On"
                        pinkNoise(60);
                        [Pink, Fs] = audioread("Pink Noise.wav");
                        sound(Pink, Fs);
                        
                        app.Vumetro.Value = 0;
                        
                    end
                    if val == "Off"
                        clear sound
                        app.Vumetro.Value = -20;
                        delete('Pink Noise.wav')
                    end
        end

        % Button pushed function: StartMeasurementButton
        function StartMeasurementButtonPushed(app, event)
                    
                    % Objetos PlayRec
                    f1 = app.StartHzEditField.Value;
                    f2 = app.EndHzEditField.Value;
                    Time = app.DurationSEditField.Value;
                    Device = app.SelectyourDeviceListBox.Value;
                    Repetitions = app.RepetitionsEditField.Value;
                    
                    
                    %%%%%%%%%% PlayRec %%%%%%%%%%
                    [Signal] = playRec(f1,f2,Time,Device,Repetitions);
                    
                    %%%%%%%%%% Main %%%%%%%%%%
                    [Suavizada, Regresion, Parametros] = main (Signal);
                    
                    
                    %%%%%%%%%% Ploteo %%%%%%%%%%
                    Irfs = Signal.SampleRate;
                    
                    ploteofrecuencia(app, Signal);
                    ploteoir(app, Signal, Irfs);
                    ploteosmoth(app,Suavizada, Irfs);
                    Tablas(app, Parametros)
                    
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
                    delete('inverted.wav','Sine Sweep.wav');    %Borrado de Archivos Temporales
                    
                    number = app.RepetitionsEditField.Value;
                    a = 1;
                    for i=1:number
                        ToDelete = ['Recorded Signal' num2str(a) '.wav'];
                        delete(ToDelete);
                        a = a + 1;
                    end
                    
                    close(app.InterfazGraficaUIFigure); %Cerrar Aplicacion
        end

        % Value changed function: SelectyourDeviceListBox
        function SelectyourDeviceListBoxValueChanged(app, event)
                    devices = getAudioDevices(audioPlayerRecorder); %Dispositivos Aptos
                    app.SelectyourDeviceListBox.Items = devices;    %Dispositivos Mostrados en la Lista
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
                    delete('inverted.wav','Sine Sweep.wav');    %Borrado de Archivos Temporales
                    
                    number = app.RepetitionsEditField.Value;
                    a = 1;
                    for i=1:number
                        ToDelete = ['Recorded Signal' num2str(a) '.wav'];
                        delete(ToDelete);
                        a = a + 1;
                    end
                    
                    close(app.InterfazGraficaUIFigure); %Cerrar Aplicacion
        end

        % Value changed function: ImportCalculateButton
        function ImportCalculateButtonValueChanged(app, event)
                    
                    %%%%%%%%%% IMPORTAR %%%%%%%%%%
                    Signal = audioImport();
                    
                    %%%%%%%%%% CALCULATE %%%%%%%%%%
                    [Suavizada, Regresion, Parametros] = main(Signal);
                    
                    %%%%%%%%%% Ploteo %%%%%%%%%%
                    Irfs = Signal.SampleRate;
                    ploteofrecuencia(app, Signal);
                    ploteoir(app, Signal, Irfs);
                    %             ploteosmoth(app,Suavizada, Irfs);
                    Tablas(app, Parametros)
                    
        end

        % Value changed function: OctaveFilterCheckBox
        function OctaveFilterCheckBoxValueChanged(app, event)
                    
                    OctaveFiltActivator = app.OctaveFilterCheckBox.Value;
                    
                    if OctaveFiltActivator == 1
                        app.OctaveFiter_Select.Enable = 'on';
                        app.ThirdOctaveFilterCheckBox.Value = 0;
                        app.NoFilterCheckBox.Value = 0;
                        app.ThirdOctaveFiter_Select.Enable = 'off';
                    else
                        app.OctaveFiter_Select.Enable = 'off';
                    end
                    
        end

        % Value changed function: NoFilterCheckBox
        function NoFilterCheckBoxValueChanged(app, event)
                    
                    NoFilterActivator = app.NoFilterCheckBox.Value;
                    
                    if NoFilterActivator == 1
                        
                        app.ThirdOctaveFilterCheckBox.Value = 0;
                        app.OctaveFilterCheckBox.Value = 0;
                        
                        app.OctaveFiter_Select.Enable = 'off';
                        app.ThirdOctaveFiter_Select.Enable = 'off';
                    end
        end

        % Value changed function: ThirdOctaveFilterCheckBox
        function ThirdOctaveFilterCheckBoxValueChanged(app, event)
                    
                    ThirdOctaveFiltActivator = app.OctaveFilterCheckBox.Value;
                    
                    if ThirdOctaveFiltActivator == 1
                        app.NoFilterCheckBox.Value = 0;
                        app.OctaveFilterCheckBox.Value = 0;
                        app.ThirdOctaveFiter_Select.Enable = 'on';
                        app.OctaveFiter_Select.Enable = 'off';
                    else
                        app.ThirdOctaveFiter_Select.Enable = 'off';
                        app.NoFilterCheckBox.Value = 0;
                    end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterfazGraficaUIFigure = uifigure;
            app.InterfazGraficaUIFigure.Name = 'Interfaz Grafica';
            app.DataMenu = uimenu(app.InterfazGraficaUIFigure);
            app.DataMenu.Text = 'Data';
            app.ExportMenu = uimenu(app.DataMenu);
            app.ExportMenu.Text = 'Export';
            app.TablexlsxMenu = uimenu(app.ExportMenu);
            app.TablexlsxMenu.Text = 'Table (.xlsx)';
            app.GeneralMenu = uimenu(app.TablexlsxMenu);
            app.GeneralMenu.Text = 'General';
            app.OctaveFilterMenu = uimenu(app.TablexlsxMenu);
            app.OctaveFilterMenu.Text = 'Octave Filter';
            app.ThirdOctaveFilterMenu = uimenu(app.TablexlsxMenu);
            app.ThirdOctaveFilterMenu.Text = 'Third Octave Filter';
            app.IRJPGMenu = uimenu(app.ExportMenu);
            app.IRJPGMenu.Text = 'IR (JPG)';
            app.SmoothIRJPGMenu = uimenu(app.ExportMenu);
            app.SmoothIRJPGMenu.Text = 'Smooth IR (JPG)';
            app.FrecuencyResponseJPGMenu = uimenu(app.ExportMenu);
            app.FrecuencyResponseJPGMenu.Text = 'Frecuency Response(JPG)';
            app.ExitMenu = uimenu(app.InterfazGraficaUIFigure);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Figures = uitabgroup(app.InterfazGraficaUIFigure);
            app.IR = uitab(app.Figures);
            app.IR.Title = 'IR';
            app.Plot_IR = uiaxes(app.IR);
            title(app.Plot_IR, 'IR')
            xlabel(app.Plot_IR, 'Time [Sec]')
            ylabel(app.Plot_IR, 'Amplitude [dB]')
            app.SmoothIR = uitab(app.Figures);
            app.SmoothIR.Title = 'Smooth IR';
            app.Plot_SmoothIr = uiaxes(app.SmoothIR);
            title(app.Plot_SmoothIr, 'Smooth IR')
            xlabel(app.Plot_SmoothIr, 'Time [Sec]')
            ylabel(app.Plot_SmoothIr, 'Amplitude [dB]')
            app.FrecuencyResponseTab = uitab(app.Figures);
            app.FrecuencyResponseTab.Title = 'Frecuency Response';
            app.Plot_FrecuencyResponse = uiaxes(app.FrecuencyResponseTab);
            title(app.Plot_FrecuencyResponse, 'Frecuency Response')
            xlabel(app.Plot_FrecuencyResponse, 'Time [Sec]')
            ylabel(app.Plot_FrecuencyResponse, 'Amplitude [dB]')
            app.Parameters = uitab(app.Figures);
            app.Parameters.Title = 'Table';
            app.Parameters_Filter = uitabgroup(app.Parameters);
            app.Parameters_General = uitab(app.Parameters_Filter);
            app.Parameters_General.Title = 'General';
            app.Tabla_General = uitable(app.Parameters_General);
            app.Tabla_General.ColumnName = {'Parameter'; '31,5 Hz'; '63 Hz'; '125 Hz '; '250 Hz'; '500 Hz'; '1000 Hz'; '2000 Hz'; '4000 Hz'; '8000 Hz'; '16000 Hz'};
            app.Tabla_General.RowName = {};
            app.Tabla_General.ColumnEditable = [false false false false false false false false false false true];
            app.Parameters_Octave = uitab(app.Parameters_Filter);
            app.Parameters_Octave.Title = 'Octave Filter';
            app.Tabla_Octave = uitable(app.Parameters_Octave);
            app.Tabla_Octave.ColumnName = {'Parameter'; '31,5 Hz'; '63 Hz'; '125 Hz '; '250 Hz'; '500 Hz'; '1000 Hz'; '2000 Hz'; '4000 Hz'; '8000 Hz'; '16000 Hz'};
            app.Tabla_Octave.RowName = {};
            app.Tabla_Octave.ColumnEditable = [false false false false false false false false false false true];
            app.Parameters_ThirdOctave = uitab(app.Parameters_Filter);
            app.Parameters_ThirdOctave.Title = 'Third Octave Filter';
            app.Tabla_ThirdOctave = uitable(app.Parameters_ThirdOctave);
            app.Tabla_ThirdOctave.ColumnName = {'Parameter'; '31,5 Hz'; '63 Hz'; '125 Hz '; '250 Hz'; '500 Hz'; '1000 Hz'; '2000 Hz'; '4000 Hz'; '8000 Hz'; '16000 Hz'};
            app.Tabla_ThirdOctave.RowName = {};
            app.Tabla_ThirdOctave.ColumnEditable = [false false false false false false false false false false true];
            app.Operaciones = uitabgroup(app.InterfazGraficaUIFigure);
            app.CalibrateTab = uitab(app.Operaciones);
            app.CalibrateTab.Title = 'Calibrate';
            app.PlaypinknoisetocalibrateyoursystemLabel = uilabel(app.CalibrateTab);
            app.PlaypinknoisetocalibrateyoursystemLabel.Text = 'Play pink noise to calibrate your system.';
            app.dBLabel = uilabel(app.CalibrateTab);
            app.dBLabel.Text = '[dB]';
            app.Vumetro = uigauge(app.CalibrateTab, 'linear');
            app.Vumetro.Limits = [-20 4];
            app.Vumetro.Value = -20;
            app.Switch = uiswitch(app.CalibrateTab, 'rocker');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @PlayNoiseButtonPushed, true);
            app.PlayRecTab = uitab(app.Operaciones);
            app.PlayRecTab.Title = 'Play/Rec';
            app.StartHzLabel = uilabel(app.PlayRecTab);
            app.StartHzLabel.Text = 'Start [Hz]';
            app.StartHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.StartHzEditField.Limits = [0 20000];
            app.EndHzLabel = uilabel(app.PlayRecTab);
            app.EndHzLabel.Text = 'End [Hz]';
            app.EndHzEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.EndHzEditField.Limits = [0 40000];
            app.DurationSEditFieldLabel = uilabel(app.PlayRecTab);
            app.DurationSEditFieldLabel.Text = 'Duration [S]';
            app.DurationSEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.DurationSEditField.Limits = [0 60];
            app.StartMeasurementButton = uibutton(app.PlayRecTab, 'push');
            app.StartMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasurementButtonPushed, true);
            app.StartMeasurementButton.Text = 'Start Measurement';
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel = uilabel(app.PlayRecTab);
            app.PlayandrecordasinesweepinordertoperformthemeasurementLabel.Text = ' Play and record a sine sweep in order to perform the measurement.';
            app.SelectyourDeviceLabel = uilabel(app.PlayRecTab);
            app.SelectyourDeviceLabel.Text = 'Select your Device';
            app.SelectyourDeviceListBox = uilistbox(app.PlayRecTab);
            app.SelectyourDeviceListBox.Items = {'Select Device'};
            app.SelectyourDeviceListBox.ValueChangedFcn = createCallbackFcn(app, @SelectyourDeviceListBoxValueChanged, true);
            app.SelectyourDeviceListBox.Value = {};
            app.RepetitionsEditFieldLabel = uilabel(app.PlayRecTab);
            app.RepetitionsEditFieldLabel.Text = 'Repetitions';
            app.RepetitionsEditField = uieditfield(app.PlayRecTab, 'numeric');
            app.RepetitionsEditField.Limits = [0 60];
            app.RepetitionsEditField.Value = 1;
            app.ImportTab = uitab(app.Operaciones);
            app.ImportTab.Title = 'Import';
            app.ImportCalculateButton = uibutton(app.ImportTab, 'state');
            app.ImportCalculateButton.ValueChangedFcn = createCallbackFcn(app, @ImportCalculateButtonValueChanged, true);
            app.ImportCalculateButton.Text = 'Import & Calculate';
            app.Label = uilabel(app.ImportTab);
            app.Label.Text = {''; 'Select the impulse responses that you want to import. '; 'Remember that they must have the same sampling frequency'};
            app.EXITButton = uibutton(app.InterfazGraficaUIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.OctaveFilter_Panel = uipanel(app.InterfazGraficaUIFigure);
            app.OctaveFilterCheckBox = uicheckbox(app.OctaveFilter_Panel);
            app.OctaveFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @OctaveFilterCheckBoxValueChanged, true);
            app.OctaveFilterCheckBox.Text = 'Octave Filter';
            app.OctaveFiter_Select = uidropdown(app.OctaveFilter_Panel);
            app.OctaveFiter_Select.Items = {'125', '250', '500', '1000', '2000', '4000', '8000'};
            app.OctaveFiter_Select.Enable = 'off';
            app.OctaveFiter_Select.Value = '1000';
            app.ThirdOctaveFilter_Panel = uipanel(app.InterfazGraficaUIFigure);
            app.ThirdOctaveFilterCheckBox = uicheckbox(app.ThirdOctaveFilter_Panel);
            app.ThirdOctaveFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThirdOctaveFilterCheckBoxValueChanged, true);
            app.ThirdOctaveFilterCheckBox.Text = 'Third Octave Filter';
            app.ThirdOctaveFiter_Select = uidropdown(app.ThirdOctaveFilter_Panel);
            app.ThirdOctaveFiter_Select.Items = {'125', '160', '200', '250', '315', '400', '500', '630', '800', '1000', '1250', '1600', '2000', '3150', '3500', '4000', '5000', '6300', '8000'};
            app.ThirdOctaveFiter_Select.Enable = 'off';
            app.ThirdOctaveFiter_Select.Value = '1000';
            app.NoFilter_Panel = uipanel(app.InterfazGraficaUIFigure);
            app.NoFilterCheckBox = uicheckbox(app.NoFilter_Panel);
            app.NoFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoFilterCheckBoxValueChanged, true);
            app.NoFilterCheckBox.Text = 'No Filter';
        end
    end

    methods (Access = public)

        % Construct app
        function app = interfazfrafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfazGraficaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfazGraficaUIFigure)
        end
    end
end